classdef grading < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GradingApp                  matlab.ui.Figure
        NextAudiospaceButton        matlab.ui.control.Button
        PlayRecordedAudioButton     matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        DEBUGMODESwitchLabel        matlab.ui.control.Label
        DEBUGMODESwitch             matlab.ui.control.Switch
        GreentorecorddecisionsLampLabel  matlab.ui.control.Label
        GreentorecorddecisionsLamp  matlab.ui.control.Lamp
        TargetWordLabel             matlab.ui.control.Label
        Label1                      matlab.ui.control.Label
        Label2                      matlab.ui.control.Label
        SaveandExitButton           matlab.ui.control.Button
        ButtonGroup                 matlab.ui.container.ButtonGroup
        Yes1Button                  matlab.ui.control.RadioButton
        No2Button                   matlab.ui.control.RadioButton
        FakeButton                  matlab.ui.control.RadioButton
        ButtonGroup_2               matlab.ui.container.ButtonGroup
        YesGButton                  matlab.ui.control.RadioButton
        NoBButton                   matlab.ui.control.RadioButton
        IstheonsetpointmarkedinthewaveplotaccurateLabel  matlab.ui.control.Label
        DoestherecordedaudiomatchthetargetwordLabel  matlab.ui.control.Label
        GraderLabel                 matlab.ui.control.Label
        experimenternameLabel       matlab.ui.control.Label
        PeriodLabel                 matlab.ui.control.Label
        to20200101000000Label       matlab.ui.control.Label
    end


    properties (Access = public)
        observerFile % ungraded file names
        decision % decisions made in this run of app
        test % in test/debug mode, the decision will not be stored
        saved % whether decision has been saved
        inTime % when does the current record be loaded
        onsetSel % onset selection response, true for 'Yes'
        audioSel % audio selection response, true for 'Yes'
        % question dialog variables
        experimenterName
        periodStart
        periodEnd
        % info of the current file
        time_
        word_
        observer_
    end

    methods (Access = private)

        % display one image from the top of 'observerFile'
        function [] = displayTop(app)

            app.UIAxes.cla;
            if isempty(app.observerFile)
                return;
            end
            fig = openfig(strcat(char(app.observerFile(1)), '.fig'), 'invisible');
            ax = findobj(fig, 'Type', 'Axes');
            copyobj(ax.Children, app.UIAxes);
            delete(fig);
        end


        % retrieve info from an observer's filename
        function [] = retrieveInfo(app)

            app.Label1.Text = '';
            if isempty(app.observerFile)
                app.writeDecisionToFile('clear');
                return;
            end
            info = split(char(app.observerFile(1)), '-');
            app.time_ = {info{1} info{2} info{3} ...
                         info{4} info{5} info{6}};
            app.word_ = info{7};
            app.observer_ = info{8};
            % tic the new word in time
            app.inTime = tic;
            % clear label 2 text, if any
            app.Label2.Text = '';
            % display new target word
            app.Label1.Text = app.word_;
            % reset onsetSel, default as true
            app.onsetSel = true;
            % reset audioSel
            app.audioSel = [];
            % default sel for onsetSel
            app.YesGButton.Value = 1;
            % to create an illusion that no default is selected for
            % audioSel
            app.FakeButton.Value = 1;
        end


        % save current decision to 'app.decision' before proceeding to next
        % word
        function [] = saveDecision(app)

            if app.saved
                return; % this app can be stored to file for only once
            end
            assert(~isempty(app.onsetSel));
            assert(~isempty(app.audioSel));
            t = {char(app.observerFile(1)), app.onsetSel, app.audioSel, strjoin(app.time_, '-'), app.word_, app.observer_, app.experimenterName};
            app.decision = [app.decision; t];
        end


        function [] = updateGUI(app)

            % retrieve next audio's info
            app.retrieveInfo();
            % display waveplot
            app.displayTop();
            % auto play sound
            pause(0.3);  % wait 300ms
            app.PlayRecordedAudioButtonPushed();
        end


        % actually write app.decision to file
        % the filename must start with 'Decision-' to be recognized
        % 'option' can be 'clear' or 'button', standing for two ways of
        % reaching this function
        function [] = writeDecisionToFile(app, option)

            if app.saved
                return; % never save twice
            end
            if app.test

                if strcmp(option, 'clear')
                    app.Label2.Text = 'All audio responses in your speficied time period have been reviewed. Your decisions will be discarded due to debug mode. You can now close this script safely.';
                elseif strcmp(option, 'button')
                    app.Label2.Text = 'Your decisions will be discarded due to debug mode. You can now close this script safely.';
                end
                app.saved = true;
                return;
            end

            folderName = fileparts(mfilename('fullpath'));
            fileName = ['Decision-', datestr(now, 'yyyy-mm-dd-HH-MM-SS'), '-', app.experimenterName, '.mat'];
            t = app.decision; % to use 'save', create a temporary variable
            save(fullfile(folderName, fileName), 't');
            if strcmp(option, 'clear')
                app.Label2.Text = 'All audio responses in your speficied time period have been reviewed. Your decisions have been stored. You can now close this script safely.';
            elseif strcmp(option, 'button')
                app.Label2.Text = 'Your decisions have been stored. You can now close this script safely.';
            else
                app.Label2.Text = 'Internal error';
            end
            app.saved = true;
        end
        
        % retrieve information from question dialog
        function [res, experimenterName, periodStart, periodEnd, skipGraded] = retrieveDialogInfo(app, answer)

            res = true;
            experimenterName = '';
            periodStart = '';
            periodEnd = '';
            skipGraded = [];
            if isempty(answer) || isempty(answer{1}) || isempty(answer{2}) || isempty(answer{3})
                res = false;
                return
            end
            % 1st part
            experimenterName = answer{1};
            % 2nd part
            count = 0;
            numArray = zeros(1, 6);
            timeConstraint = strrep(answer{2}, ':', '-');
            timeConstraint = strrep(timeConstraint, ' ', '-');
            delimiterSplit = strsplit(timeConstraint, '-');
            for i = 1:length(delimiterSplit)
                % split by ' '/':'/'-'

                temp = str2double(delimiterSplit{i});
                if ~isempty(temp) && ~isnan(temp)
                    count = count + 1;
                    if count>12
                        res = false;
                        return;
                    end
                    numArray(count) = temp;
                end
            end
            if count~=12 || numArray(1)<1900 || numArray(1)>2100 || numArray(7)<1900 || numArray(7)>2100 || ...
               numArray(6)>59 || numArray(12)>59
                
                % very vague check
                res = false;
                return;
            end
            periodStart = string(sprintf('%04d-%02d-%02d-%02d-%02d-%02d', numArray(1), numArray(2), numArray(3), numArray(4), numArray(5), numArray(6)));
            periodEnd = string(sprintf('%04d-%02d-%02d-%02d-%02d-%02d', numArray(7), numArray(8), numArray(9), numArray(10), numArray(11), numArray(12)));
            % 3rd part
            text = lower(answer{3});
            if strcmp(text, 'true') || strcmp(text, 'yes') || strcmp(text, 'ok') || strcmp(text, 'skip') || strcmp(text, '1') || strcmp(text, 'y') || strcmp(text, 't')
                skipGraded = true;
            end
            if strcmp(text, 'false') || strcmp(text, 'no') || strcmp(text, 'nope') || strcmp(text, 'don''t') || strcmp(text, '0') || strcmp(text, 'n') || strcmp(text, 'f')
                skipGraded = false;
            end
            if isempty(skipGraded)
                res = false;
                return;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % input dialog box
            prompt = {'Grader name:','Enter time period (yyyy-mm-dd-HH:MM:SS):','Skip graded observer responses?'};
            dlgtitle = 'Grading App';
            dims = [1 60];
            definput = {'',sprintf('%s to %s', '2020-1-1-00:00:00', datestr(now, 'yyyy-mm-dd-HH:MM:SS')), 'true'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            [flag, app.experimenterName, app.periodStart, app.periodEnd, skipGraded] = retrieveDialogInfo(app, answer);
            if ~flag
                warning('Failed to process question dialog response. Grading app terminated.');
                closereq;
                return;
            end

            % initialize
            app.experimenternameLabel.Text = app.experimenterName;
            app.to20200101000000Label.Text = sprintf('%s to %s', app.periodStart, app.periodEnd);
            app.inTime = tic;
            app.saved = false;
            app.test = false;
            app.Label2.Text = '';
            app.decision = cell2table(cell(0, 7), 'VariableNames', {'fileName', 'onsetValid', 'audioValid', 'time', 'word', 'observer', 'grader'});
            app.onsetSel = true; % default as true
            app.audioSel = []; % no default
            app.FakeButton.Value = 1; % to create an illusion that no default is selected for audioSel
            % read all filenames
            dinfo = dir(fileparts(mfilename('fullpath')));
            files = {dinfo.name};
            % extract all file names
            app.observerFile = strings(size(files));
            count = 0;
            for i = 1:length(files)
                
                file = files{i};
                % use wav file as identifier
                if length(file)>4 && strcmp(file(end-3:end), '.wav')
                    count = count + 1;
                    app.observerFile(count) = string(file(1:end-4));
                end
            end
            app.observerFile = app.observerFile(1:count);
            % remove all graded file names
            if skipGraded
                for i = 1:length(files)
    
                    file = files{i};
                    if length(file)>9 && strcmp(file(1:9), 'Decision-')
    
                        t = load(file);
                        t = t.t;
                        for j = 1:height(t)
    
                            mask = (app.observerFile == t{j, 1}{1});
                            app.observerFile = app.observerFile(~mask);
                        end
                    end
                end
            end
            % remove files outside of the specified time period
            mask = true(1, length(app.observerFile));
            for i = 1:length(app.observerFile)
                
                fileTime = extractBefore(app.observerFile(i), 20);
                if (fileTime > app.periodEnd) || (fileTime < app.periodStart)
                    mask(i) = false;
                end
            end
            app.observerFile = app.observerFile(mask);

            % Load first word
            app.updateGUI();
        end

        % Button pushed function: PlayRecordedAudioButton
        function PlayRecordedAudioButtonPushed(app, event)
            
            if isempty(app.observerFile) || app.saved
                return;
            end
            [y, fs] = audioread(strcat(char(app.observerFile(1)), '.wav'));
            sound(y, fs);
        end

        % Value changed function: DEBUGMODESwitch
        function DEBUGMODESwitchValueChanged(app, event)

            if strcmp(app.DEBUGMODESwitch.Value, 'On')
                app.test = true;
            else
                app.test = false;
            end
            if ~app.test
                app.GreentorecorddecisionsLamp.Color = [0.2, 0.8, 0.2];
            else
                app.GreentorecorddecisionsLamp.Color = [0.8, 0.2, 0.2];
            end
        end

        % Button pushed function: NextAudiospaceButton
        function NextAudiospaceButtonPushed(app, event)
            
            % Do not process mouseClick appeared too fast
            if toc(app.inTime) < 1.0
                app.Label2.Text = 'Mouse click too fast';
                return;
            end
            % whether necessary decisions have been made
            if isempty(app.audioSel)
                app.Label2.Text = 'Please answer the second question before proceeding. The first question has a default response ''yes''.';
                return;
            end
            % save decision
            if ~app.test
                app.saveDecision();
            end
            % load next response
            app.observerFile = app.observerFile(2:end);
            app.updateGUI();
        end

        % Key press function: GradingApp
        function KeyPress(app, event)
            
            % Do not process keyPress appeared too fast
            if toc(app.inTime) < 1.0
                app.Label2.Text = 'Keyboard press too fast';
                return;
            end
            
            key = event.Key;
            if strcmp(key, '1')
                app.Yes1Button.Value = 1; % this will automatically change the other button to false
                app.audioSel = true;
                return;
            end
            if strcmp(key, '2')
                app.No2Button.Value = 1;
                app.audioSel = false;
                return;
            end
            if strcmp(key, 'g')
                app.YesGButton.Value = 1;
                app.onsetSel = true;
                return;
            end
            if strcmp(key, 'b')
                app.NoBButton.Value = 1;
                app.onsetSel = false;
                return;
            end
            if strcmp(key, 'space')
                app.NextAudiospaceButtonPushed();
                return;
            end
        end

        % Button pushed function: SaveandExitButton
        function SaveandExitButtonPushed(app, event)
            
            app.writeDecisionToFile('button');
            app.observerFile = [];
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            % onset selection
            % This function should only change 'app.onsetSel' and nothing
            % more
            
            % Do not process mouseClick appeared too fast
            if toc(app.inTime) < 1.0
                app.Label2.Text = 'Mouse click too fast';
                return;
            end

            selectedButton = app.ButtonGroup_2.SelectedObject;
            if selectedButton.Text(1) == 'Y'
                app.onsetSel = true;
            else
                app.onsetSel = false;
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % audio selection
            % This function should only change 'app.audioSel' and nothing
            % more
            
            % Do not process mouseClick appeared too fast
            if toc(app.inTime) < 1.0
                app.Label2.Text = 'Mouse click too fast';
                return;
            end

            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton.Text(1) == 'Y'
                app.audioSel = true;
            else
                app.audioSel = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GradingApp = uifigure('Visible', 'off');
            app.GradingApp.Name = 'Grading App';
            app.GradingApp.KeyPressFcn = createCallbackFcn(app, @KeyPress, true);
            app.NextAudiospaceButton = uibutton(app.GradingApp, 'push');
            app.NextAudiospaceButton.ButtonPushedFcn = createCallbackFcn(app, @NextAudiospaceButtonPushed, true);
            app.NextAudiospaceButton.Text = 'Next Audio (space)';
            app.PlayRecordedAudioButton = uibutton(app.GradingApp, 'push');
            app.PlayRecordedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRecordedAudioButtonPushed, true);
            app.PlayRecordedAudioButton.Text = 'Play Recorded Audio';
            app.UIAxes = uiaxes(app.GradingApp);
            title(app.UIAxes, 'Audio Waveplot')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            app.DEBUGMODESwitchLabel = uilabel(app.GradingApp);
            app.DEBUGMODESwitchLabel.Text = 'DEBUG MODE';
            app.DEBUGMODESwitch = uiswitch(app.GradingApp, 'slider');
            app.DEBUGMODESwitch.ValueChangedFcn = createCallbackFcn(app, @DEBUGMODESwitchValueChanged, true);
            app.GreentorecorddecisionsLampLabel = uilabel(app.GradingApp);
            app.GreentorecorddecisionsLampLabel.Text = 'Green to record decisions';
            app.GreentorecorddecisionsLamp = uilamp(app.GradingApp);
            app.TargetWordLabel = uilabel(app.GradingApp);
            app.TargetWordLabel.Text = 'Target Word:';
            app.Label1 = uilabel(app.GradingApp);
            app.Label1.Text = 'Label1';
            app.Label2 = uilabel(app.GradingApp);
            app.Label2.Text = 'Label2';
            app.SaveandExitButton = uibutton(app.GradingApp, 'push');
            app.SaveandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandExitButtonPushed, true);
            app.SaveandExitButton.Text = 'Save and Exit';
            app.ButtonGroup = uibuttongroup(app.GradingApp);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Yes1Button = uiradiobutton(app.ButtonGroup);
            app.Yes1Button.Text = 'Yes (1)';
            app.Yes1Button.Value = true;
            app.No2Button = uiradiobutton(app.ButtonGroup);
            app.No2Button.Text = 'No (2)';
            app.FakeButton = uiradiobutton(app.ButtonGroup);
            app.FakeButton.Visible = 'off';
            app.FakeButton.Text = 'Fake';
            app.ButtonGroup_2 = uibuttongroup(app.GradingApp);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.YesGButton = uiradiobutton(app.ButtonGroup_2);
            app.YesGButton.Text = 'Yes (G)';
            app.YesGButton.Value = true;
            app.NoBButton = uiradiobutton(app.ButtonGroup_2);
            app.NoBButton.Text = 'No (B)';
            app.IstheonsetpointmarkedinthewaveplotaccurateLabel = uilabel(app.GradingApp);
            app.IstheonsetpointmarkedinthewaveplotaccurateLabel.Text = 'Is the onset point marked in the waveplot accurate?';
            app.DoestherecordedaudiomatchthetargetwordLabel = uilabel(app.GradingApp);
            app.DoestherecordedaudiomatchthetargetwordLabel.Text = 'Does the recorded audio match the target word?';
            app.GraderLabel = uilabel(app.GradingApp);
            app.GraderLabel.Text = 'Grader:';
            app.experimenternameLabel = uilabel(app.GradingApp);
            app.experimenternameLabel.Text = 'experimenter name';
            app.PeriodLabel = uilabel(app.GradingApp);
            app.PeriodLabel.Text = 'Period:';
            app.to20200101000000Label = uilabel(app.GradingApp);
            app.to20200101000000Label.Text = '2020-01-01-00:00:00 to 2020-01-01-00:00:00';
            % Show the figure after all components are created
            app.GradingApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = grading

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GradingApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GradingApp)
        end
    end
end